classdef CurrentGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        WelcomeLabel                    matlab.ui.control.Label
        HomesteadFLButton_2             matlab.ui.control.Button
        ChelanCountyWAButton_2          matlab.ui.control.Button
        GreatPlainsWestMTButton_2       matlab.ui.control.Button
        DemoModeLabel_2                 matlab.ui.control.Label
        InputDataTab                    matlab.ui.container.Tab
        LoadSolarDataButton             matlab.ui.control.Button
        LoadDemandDataButton            matlab.ui.control.Button
        LoadDataLabel                   matlab.ui.control.Label
        TextArea_2                      matlab.ui.control.TextArea
        SolarArrayScaleFactorEditFieldLabel  matlab.ui.control.Label
        SolarArrayScaleFactorEditField  matlab.ui.control.NumericEditField
        SolarGenerationCheckBox         matlab.ui.control.CheckBox
        ElectricityDemandCheckBox       matlab.ui.control.CheckBox
        DatatoGraphLabel                matlab.ui.control.Label
        StartDatetographSliderLabel     matlab.ui.control.Label
        StartDatetographSlider          matlab.ui.control.Slider
        EndDatetographSliderLabel       matlab.ui.control.Label
        EndDatetographSlider            matlab.ui.control.Slider
        MaximumSliderLabel              matlab.ui.control.Label
        MaximumSlider                   matlab.ui.control.Slider
        MinimumSliderLabel              matlab.ui.control.Label
        MinimumSlider                   matlab.ui.control.Slider
        SizeofSolarArrayUploadedMWEditFieldLabel  matlab.ui.control.Label
        SizeofSolarArrayUploadedMWEditField  matlab.ui.control.NumericEditField
        DemandScaleFactorEditFieldLabel  matlab.ui.control.Label
        DemandScaleFactorEditField      matlab.ui.control.NumericEditField
        SubstationCapacityMWEditFieldLabel  matlab.ui.control.Label
        SubstationCapacityMWEditField   matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        SystemSpecificationsTab         matlab.ui.container.Tab
        ESSAlgorithmButtonGroup         matlab.ui.container.ButtonGroup
        TimeBasedButton                 matlab.ui.control.ToggleButton
        LoadBasedButton                 matlab.ui.control.ToggleButton
        BatteryReplacementButtonGroup   matlab.ui.container.ButtonGroup
        YearsButton                     matlab.ui.control.ToggleButton
        CyclesButton                    matlab.ui.control.ToggleButton
        MinimumSOHButton                matlab.ui.control.ToggleButton
        ThroughputMWhButton             matlab.ui.control.ToggleButton
        ESSDepthofDischargeLabel        matlab.ui.control.Label
        ESSDepthofDischargeEditField    matlab.ui.control.NumericEditField
        ESSDurationhoursEditFieldLabel  matlab.ui.control.Label
        ESSDurationhoursEditField       matlab.ui.control.NumericEditField
        ESSInitialEnergyofMaximumEditFieldLabel  matlab.ui.control.Label
        ESSInitialEnergyofMaximumEditField  matlab.ui.control.NumericEditField
        SubstationPlannedRatingMWEditFieldLabel  matlab.ui.control.Label
        SubstationPlannedRatingMWEditField  matlab.ui.control.NumericEditField
        UnitsSwitchLabel                matlab.ui.control.Label
        UnitsSwitch                     matlab.ui.control.Switch
        PeakHoursEditFieldLabel         matlab.ui.control.Label
        PeakHoursEditField              matlab.ui.control.NumericEditField
        OffPeakHoursEditFieldLabel      matlab.ui.control.Label
        OffPeakHoursEditField           matlab.ui.control.NumericEditField
        BatteryReplacementEditFieldLabel  matlab.ui.control.Label
        BatteryReplacementEditField     matlab.ui.control.NumericEditField
        YearlyESSCapacityLossEditFieldLabel  matlab.ui.control.Label
        YearlyESSCapacityLossEditField  matlab.ui.control.NumericEditField
        YearlySolarCapacityLossEditFieldLabel  matlab.ui.control.Label
        YearlySolarCapacityLossEditField  matlab.ui.control.NumericEditField
        MonthlyESSchargeLossEditFieldLabel  matlab.ui.control.Label
        MonthlyESSchargeLossEditField   matlab.ui.control.NumericEditField
        InverterEfficiencyLabel         matlab.ui.control.Label
        InverterEfficiencyEditField     matlab.ui.control.NumericEditField
        DCDCConverterEfficiencyEditFieldLabel  matlab.ui.control.Label
        DCDCConverterEfficiencyEditField  matlab.ui.control.NumericEditField
        ESSRoundTripEfficiencyLabel     matlab.ui.control.Label
        ESSRoundTripEfficiencyEditField  matlab.ui.control.NumericEditField
        HardwareReplacementIntervalYearsEditFieldLabel  matlab.ui.control.Label
        HardwareReplacementIntervalYearsEditField  matlab.ui.control.NumericEditField
        PeakHoursEndTimeEditField       matlab.ui.control.NumericEditField
        OffPeakHoursEndTimeEditField    matlab.ui.control.NumericEditField
        SystemSpecificationsLabel       matlab.ui.control.Label
        EmergencyPowerofMaximumEditFieldLabel  matlab.ui.control.Label
        EmergencyPowerofMaximumEditField  matlab.ui.control.NumericEditField
        ChargebySolarThresholdLabel     matlab.ui.control.Label
        ChargebySolarThresholdEditField  matlab.ui.control.NumericEditField
        ChargePercentageEditFieldLabel  matlab.ui.control.Label
        ChargePercentageEditField       matlab.ui.control.NumericEditField
        DischargePercentageEditFieldLabel  matlab.ui.control.Label
        DischargePercentageEditField    matlab.ui.control.NumericEditField
        DischargeFactorEditFieldLabel   matlab.ui.control.Label
        DischargeFactorEditField        matlab.ui.control.NumericEditField
        CostVariablesTab                matlab.ui.container.Tab
        CarbonCostpertonCO2USDEditFieldLabel  matlab.ui.control.Label
        CarbonCostpertonCO2USDEditField  matlab.ui.control.NumericEditField
        SolarInstallationCostperMWUSDEditFieldLabel  matlab.ui.control.Label
        SolarInstallationCostperMWUSDEditField  matlab.ui.control.NumericEditField
        SolarFixedInstallationCostUSDEditFieldLabel  matlab.ui.control.Label
        SolarFixedInstallationCostUSDEditField  matlab.ui.control.NumericEditField
        ESSInstallationCostperMWhUSDEditFieldLabel  matlab.ui.control.Label
        ESSInstallationCostperMWhUSDEditField  matlab.ui.control.NumericEditField
        ESSFixedInstallationCostUSDEditFieldLabel  matlab.ui.control.Label
        ESSFixedInstallationCostUSDEditField  matlab.ui.control.NumericEditField
        SolarOperationsandMaintenanceCostperMWUSDEditFieldLabel  matlab.ui.control.Label
        SolarOperationsandMaintenanceCostperMWUSDEditField  matlab.ui.control.NumericEditField
        ESSOperationsandMaintenanceCostperMWhUSDEditFieldLabel  matlab.ui.control.Label
        ESSOperationsandMaintenanceCostperMWhUSDEditField  matlab.ui.control.NumericEditField
        ESSFixedOperationsandMaintenanceCostUSDEditFieldLabel  matlab.ui.control.Label
        ESSFixedOperationsandMaintenanceCostUSDEditField  matlab.ui.control.NumericEditField
        SubstationUpgradeCostperMWUSDEditFieldLabel  matlab.ui.control.Label
        SubstationUpgradeCostperMWUSDEditField  matlab.ui.control.NumericEditField
        SolarPercentYearlyCostReductionLabel  matlab.ui.control.Label
        SolarPercentYearlyCostReductionEditField  matlab.ui.control.NumericEditField
        ESSPercentYearlyCostReductionLabel  matlab.ui.control.Label
        ESSPercentYearlyCostReductionEditField  matlab.ui.control.NumericEditField
        SolarFixedOperationsandMaintenanceCostUSDEditField_2Label  matlab.ui.control.Label
        SolarFixedOperationsandMaintenanceCostUSDEditField_2  matlab.ui.control.NumericEditField
        SubstationOperationsandMaintenanceCostperMWUSDEditFieldLabel  matlab.ui.control.Label
        SubstationOperationsandMaintenanceCostperMWUSDEditField  matlab.ui.control.NumericEditField
        ESSReplacementCostperMWhUSDEditFieldLabel  matlab.ui.control.Label
        ESSReplacementCostperMWhUSDEditField  matlab.ui.control.NumericEditField
        ESSFixedReplacementCostUSDEditFieldLabel  matlab.ui.control.Label
        ESSFixedReplacementCostUSDEditField  matlab.ui.control.NumericEditField
        ESSReplacementperMWhCostUSDLabel  matlab.ui.control.Label
        ESSReplacementperMWhCostUSDEditField  matlab.ui.control.NumericEditField
        SolarInstallationCarbonCostperMWtonsCO2EditFieldLabel  matlab.ui.control.Label
        SolarInstallationCarbonCostperMWtonsCO2EditField  matlab.ui.control.NumericEditField
        StorageInstallationCarbonCostperMWtonsCO2EditFieldLabel  matlab.ui.control.Label
        StorageInstallationCarbonCostperMWtonsCO2EditField  matlab.ui.control.NumericEditField
        SubstationUpgradeCarbonCostperMWtonsCO2EditFieldLabel  matlab.ui.control.Label
        SubstationUpgradeCarbonCostperMWtonsCO2EditField  matlab.ui.control.NumericEditField
        SolarOperationsandMaintenanceCarbonCostperMWtonsCO2Label  matlab.ui.control.Label
        SubstationUpgradeCarbonEquivalentCostperMWtonsCO2EditField_2  matlab.ui.control.NumericEditField
        StorageOperationsandMaintenanceCarbonCostperMWtonsCO2Label  matlab.ui.control.Label
        SubstationUpgradeCarbonEquivalentCostperMWtonsCO2EditField_3  matlab.ui.control.NumericEditField
        SubstationOperationsandMaintenanceCarbonCostperMWtonsCO2Label  matlab.ui.control.Label
        SubstationUpgradeCarbonEquivalentCostperMWtonsCO2EditField_4  matlab.ui.control.NumericEditField
        HardwareReplacementCarbonCostperMWtonsCO2Label  matlab.ui.control.Label
        SubstationUpgradeCarbonEquivalentCostperMWtonsCO2EditField_5  matlab.ui.control.NumericEditField
        CostVariablesLabel              matlab.ui.control.Label
        PeakHoursCosttoGenerateElectricityperMWUSDEditFieldLabel  matlab.ui.control.Label
        PeakHoursCosttoGenerateElectricityperMWUSDEditField  matlab.ui.control.NumericEditField
        OffPeakHoursCosttoGenerateElectricityperMWUSDEditFieldLabel  matlab.ui.control.Label
        OffPeakHoursCosttoGenerateElectricityperMWUSDEditField  matlab.ui.control.NumericEditField
        InterestRateEditFieldLabel      matlab.ui.control.Label
        InterestRateEditField           matlab.ui.control.NumericEditField
        CarbonCostsLabel                matlab.ui.control.Label
        MonetaryCostsLabel              matlab.ui.control.Label
        OptimizationTab                 matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        OptimizationVariablesTab        matlab.ui.container.Tab
        ProjectLifetimeYearsEditFieldLabel  matlab.ui.control.Label
        ProjectLifetimeYearsEditField   matlab.ui.control.NumericEditField
        BlackoutatSubstationRatingCheckBox  matlab.ui.control.CheckBox
        CarbonEmissionsperMWhofEnergyProducedtonsCO2EditFieldLabel  matlab.ui.control.Label
        CarbonEmissionsperMWhofEnergyProducedtonsCO2EditField  matlab.ui.control.NumericEditField
        OverloadBlackoutCostperHourUSDEditFieldLabel  matlab.ui.control.Label
        OverloadBlackoutCostperHourUSDEditField  matlab.ui.control.NumericEditField
        FixedOverloadBlackoutCostUSDEditFieldLabel  matlab.ui.control.Label
        FixedOverloadBlackoutCostUSDEditField  matlab.ui.control.NumericEditField
        SolarCapacityRangeforOptimizationMWEditFieldLabel  matlab.ui.control.Label
        SolarCapacityRangeforOptimizationMWEditField  matlab.ui.control.NumericEditField
        StorageCapacityRangeforOptimizationMWhEditFieldLabel  matlab.ui.control.Label
        StorageCapacityRangeforOptimizationMWhEditField  matlab.ui.control.NumericEditField
        SubstationUpgradeRangerforOptimizationMWEditFieldLabel  matlab.ui.control.Label
        SubstationUpgradeRangerforOptimizationMWEditField  matlab.ui.control.NumericEditField
        TextArea_3                      matlab.ui.control.TextArea
        OptimizationVariablesLabel      matlab.ui.control.Label
        PredictedYearlyLoadGrowthEditField_2Label  matlab.ui.control.Label
        PredictedYearlyLoadGrowthEditField_2  matlab.ui.control.NumericEditField
        ManualDataEntryTab              matlab.ui.container.Tab
        SolarTable                      matlab.ui.control.Table
        ESSTable                        matlab.ui.control.Table
        SubstationTable                 matlab.ui.control.Table
        SolarScenarioAmountEditFieldLabel  matlab.ui.control.Label
        SolarScenarioAmountEditField    matlab.ui.control.NumericEditField
        ESSScenarioAmountEditFieldLabel  matlab.ui.control.Label
        ESSScenarioAmountEditField      matlab.ui.control.NumericEditField
        SubstationScenarioAmountEditFieldLabel  matlab.ui.control.Label
        SubstationScenarioAmountEditField  matlab.ui.control.NumericEditField
        TextArea_4                      matlab.ui.control.TextArea
        ResultsTab                      matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        OptimizationResultsTab          matlab.ui.container.Tab
        ReturnsandCarbonEmissionsTab    matlab.ui.container.Tab
        DemandGraphsTab                 matlab.ui.container.Tab
        OverloadsTab                    matlab.ui.container.Tab
        AboutthistoolTab                matlab.ui.container.Tab
    end

    
    properties (Access = private)
        
        %data
        solarData % solar data, single-column CSV
        demandData % solar data, single-column CSV
        isGraphSolar % logical value to graph solar generation data
        isGraphDemand % logical value to graph electricity demand data
        startDateGraph % index of the first data point graphed
        endDateGraph % index of the last data point graphed
        dataLength % amount of indices in data entered 
        graphYMax % maximum y value graphed, as % of largest data point entered
        graphYMin % minumum y value graphed, as % of largest data point entered
        dataMax % maximum value of all solar and demand data entered
        solarScenarioAmount % amount of scenarios for which solar data will be manually entered
        ESSScenarioAmount % amount of scenarios for which ESS data will be manually entered
        substationScenarioAmount % amount of scenarios for which substation data will be manually entered
        const = struct % constant struct that will contain almost all of the information passed into the GUI
        solarInputSize % size (in MW) of the solar array for which generation data was entered
        solarDisplaySize % factor by which to scale the solar generation data entered (for preliminary graphing)
        demandScaleFactor % factor by which to scale the demand data entered (for preliminary graphing)
        substationCapacityGraph % substation capacity entered on Input Data tab, shown as a dotted line on the graph
    end
    
    %helper functions
    methods (Access = private)
        
        function result = initializeSlider(app)
            tickspace = floor(app.dataLength/12);
            
            app.StartDatetographSlider.Limits = [1 app.dataLength];
            app.StartDatetographSlider.MajorTicks = [1 tickspace 2*tickspace 3*tickspace 4*tickspace 5*tickspace 6*tickspace 7*tickspace 8*tickspace 9*tickspace 10*tickspace 11*tickspace 12*tickspace];
            app.StartDatetographSlider.MajorTickLabels = {'Jan', 'Feb', 'Mar', 'Apr', 'May', 'Jun', 'Jul', 'Aug', 'Sep', 'Oct', 'Nov', 'Dec', ' '};
            app.StartDatetographSlider.Value = 1;
            app.startDateGraph = 1;
            
            app.EndDatetographSlider.Limits = [1 app.dataLength];
            app.EndDatetographSlider.MajorTicks = [1 tickspace 2*tickspace 3*tickspace 4*tickspace 5*tickspace 6*tickspace 7*tickspace 8*tickspace 9*tickspace 10*tickspace 11*tickspace 12*tickspace];
            app.EndDatetographSlider.MajorTickLabels = {'Jan', 'Feb', 'Mar', 'Apr', 'May', 'Jun', 'Jul', 'Aug', 'Sep', 'Oct', 'Nov', 'Dec', ' '};
            app.EndDatetographSlider.Value = app.dataLength;
            app.endDateGraph = app.dataLength;
            
            app.dataMax = max([max(app.demandData) max(app.solarData)]);
            app.MinimumSlider.Value = 1;
            app.graphYMin = 1;
            app.MaximumSlider.Value = 100;
            app.graphYMax = 100;
            
            yline(app.UIAxes,app.substationCapacityGraph,'--');
            
            result = 1;
        end
        
        function result = initializeAxes(app)
            tickspace = floor(app.dataLength/12);
            ylabel(app.UIAxes,'MWh');
            title(app.UIAxes, 'Data Entered');
            xticks(app.UIAxes,[0 tickspace 2*tickspace 3*tickspace 4*tickspace 5*tickspace 6*tickspace 7*tickspace 8*tickspace 9*tickspace 10*tickspace 11*tickspace]);
            xticklabels(app.UIAxes,{'Jan', 'Feb', 'Mar', 'Apr', 'May', 'Jun', 'Jul', 'Aug', 'Sep', 'Oct', 'Nov', 'Dec'});
            result = 1;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SolarTable.ColumnName = {'Solar Array Size (MW)'; 'Installation Cost (USD)'; ' Yearly O&M Cost (USD)'};
            app.SolarTable.RowName = {'Scenario 1'; 'Scenario 2'};
            app.SolarTable.RowName = {};
            app.SolarTable.Data = [0,0,0;0,0,0];
            ncols = size(app.SolarTable.Data,2);
            app.SolarTable.ColumnEditable = true(1,ncols);
            
            app.ESSTable.ColumnName = {'ESS Installation Size (MW)'; 'Installation Cost (USD)'; ' Yearly O&M Cost (USD)'};
            app.ESSTable.RowName = {'Scenario 1'; 'Scenario 2'};
            app.ESSTable.RowName = {};
            app.ESSTable.Data = [0,0,0;0,0,0];
            ncols = size(app.ESSTable.Data,2);
            app.ESSTable.ColumnEditable = true(1,ncols);
            
            app.SubstationTable.ColumnName = {'Substation Upgrade Size (MW)'; 'Installation Cost(USD)'; 'O&M Cost (USD)'};
            app.SubstationTable.RowName = {'Scenario 1'; 'Scenario 2'};
            app.SubstationTable.RowName = {};
            app.SubstationTable.Data = [0,0,0;0,0,0];
            ncols = size(app.SubstationTable.Data,2);
            app.SubstationTable.ColumnEditable = true(1,ncols);
            
            app.YearlyESSCapacityLossEditFieldLabel.Tooltip = 'The percent by which the maximum energy capacity of the ESS is expected to degrade every year';
            app.YearlySolarCapacityLossEditFieldLabel.Tooltip = 'The percent by which the maximum power output of the PV panels is expected to degrade every year';
            
            app.solarInputSize = 1;
            app.SizeofSolarArrayUploadedMWEditField.Value = 1;
            app.solarDisplaySize = 1;
            app.SolarArrayScaleFactorEditField.Value = 1;
            app.substationCapacityGraph = 1;
            app.SubstationCapacityMWEditField.Value = 1;
            app.demandScaleFactor = 1;
            app.DemandScaleFactorEditField.Value = 1;


        end

        % Button pushed function: LoadSolarDataButton
        function LoadSolarDataButtonPushed(app, event)
            [solarFile,solarPath] = uigetfile('*.csv','Select a single-column CSV file of Solar Generation data');
            
            if isequal(solarFile, 0)
                return;
            end
            
            solarFile = strcat(solarPath,solarFile);
            app.solarData = readmatrix(solarFile);
            
            app.solarData(isnan(app.solarData)) = 0;
            
            if min(size(app.solarData)) > 1 || isequal(app.solarData,0)
                clear app.solarData;
                msgbox('The solar data entered was not a single-column CSV file. Please enter a single-column CSV file', 'Error', 'error');
            end
            
            if length(app.solarData) > 9000
                err = questdlg('The data entered seems to be longer than one year of hourly data. Continuing may produce unexpected results','Error','Truncate Data','Data Time Increment is not 1 Hour','Continue','Truncate Data');
                switch err
                    case 'Truncate data'
                        app.solarData = app.solarData(1:8784);
                    case 'Data Time Increment is not 1 Hour'
                        err2 = questdlg('The data will be averaged to 1 hour increments. What is the time encrement of the data entered?','Error','Minutes','Seconds','Enter New Data','Enter New Data');
                        switch err2
                            case 'Minutes'
                                newSolarData = zeros(8784,1);
                                solarTotal = 0;
                                for i = 1:length(app.solarData)
                                    solarTotal = solarTotal + app.solarData(i);
                                    if mod(i,60) == 0
                                        newSolarData(i/60) = solarTotal/60;
                                        solarTotal = 0;
                                    end
                                end
                                app.solarData = newSolarData;
                            case 'Seconds'
                                newSolarData = zeros(8784,1);
                                solarTotal = 0;
                                for i = 1:length(app.solarData)
                                    solarTotal = solarTotal + app.solarData(i);
                                    if mod(i,3600) == 0
                                        newSolarData(i/3600) = solarTotal/3600;
                                        solarTotal = 0;
                                    end
                                end
                                app.solarData = newSolarData;
                            case 'Enter New Data'
                                clear app.solarData;
                        end
                end
            end
            
            if ~isempty(app.demandData)
                if ~isequal(length(app.demandData),length(app.solarData))
                    err = questdlg('The solar data entered is not the same length as the electricity demand data entered. Would you like to truncate the longer data set or enter new data?','Error','Truncate','Enter new data','Truncate');
                    switch err
                        case 'Truncate'
                            if length(app.solarData) > length(app.demandData)
                                app.solarData = app.solarData(1:length(app.demandData));
                            else
                                app.demandData = app.demandData(1:length(app.solarData));
                            end
                        case 'Enter New Data'
                            clear app.solarData;
                    end
                end
            end
            
            app.dataLength = length(app.solarData);
            
            initializeSlider(app);
            
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            
            app.const.solarGen1MW = app.solarData ./ app.solarInputSize;
        end

        % Button pushed function: LoadDemandDataButton
        function LoadDemandDataButtonPushed(app, event)
            [demandFile,demandPath] = uigetfile('*.csv','Select a single-column CSV file of Demand data');
            
            if isequal(demandFile, 0)
                return;
            end
            
            demandFile = strcat(demandPath,demandFile);
            app.demandData = readmatrix(demandFile);
            
            app.demandData(isnan(app.demandData)) = 0;
            
            if min(size(app.demandData)) > 1 || isequal(app.demandData,0)
                clear app.demandData;
                msgbox('The input was not a single-column CSV file. Please input a single-column CSV file', 'Error', 'error');
            end
            
            if length(app.demandData) > 9000
                err = questdlg('The data entered seems to be longer than one year of hourly data. Continuing may produce unexpected results','Error','Truncate Data','Data Time Increment is not 1 Hour','Continue','Truncate Data');
                switch err
                    case 'Truncate data'
                        app.demandData = app.demandData(1:8784);
                    case 'Data Time Increment is not 1 Hour'
                        err2 = questdlg('The data will be averaged to 1 hour increments. What is the time encrement of the data entered?','Error','Minutes','Seconds','Enter New Data','Enter New Data');
                        switch err2
                            case 'Minutes'
                                newDemandData = zeros(1:8784);
                                demandTotal = 0;
                                for i = 1:length(app.demandData)
                                    demandTotal = demandTotal + app.demandData(i);
                                    if mod(i,60) == 0
                                        newDemandData(i/60) = demandTotal/60;
                                        demandTotal = 0;
                                    end
                                end
                                app.demandData = newDemandData;
                            case 'Seconds'
                                newDemandData = zeros(1:8784);
                                demandTotal = 0;
                                for i = 1:length(app.demandData)
                                    demandTotal = demandTotal + app.demandData(i);
                                    if mod(i,3600) == 0
                                        newDemandData(i/3600) = demandTotal/3600;
                                        demandTotal = 0;
                                    end
                                end
                                app.demandData = newDemandData;
                            case 'Enter New Data'
                                clear app.demandData;
                        end
                end
            end
            
            if ~isempty(app.solarData)
                if ~isequal(length(app.demandData),length(app.solarData))
                    clear app.demandData;
                    err = questdlg('The electricity demand data entered is not the same length as the solar data entered. Would you like to truncate the longer data set or enter new data?','Error','Truncate','Enter new data','Truncate');
                    switch err
                        case 'Truncate'
                            if length(app.solarData) > length(app.demandData)
                                app.solarData = app.solarData(1:length(app.demandData));
                            else
                                app.demandData = app.demandData(1:length(app.solarData));
                            end
                        case 'Enter New Data'
                            clear app.demandData;
                    end
                end
            end
            
            
            app.const.load = app.demandData;
            
            initializeSlider(app);
            
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            
            app.dataLength = length(app.demandData);
        end

        % Value changed function: SolarGenerationCheckBox
        function SolarGenerationCheckBoxValueChanged(app, event)
            app.isGraphSolar = app.SolarGenerationCheckBox.Value;
            hold(app.UIAxes,"on");
            
            if app.isGraphSolar
                initializeAxes(app);
                plot(app.UIAxes,app.solarData*app.solarDisplaySize,'-y'); 
            else
                cla(app.UIAxes);
                if app.isGraphDemand
                    plot(app.UIAxes,app.demandData, '-r');
                end
                yline(app.UIAxes,app.substationCapacityGraph,'--');
            end
        end

        % Value changed function: ElectricityDemandCheckBox
        function ElectricityDemandCheckBoxValueChanged(app, event)
            app.isGraphDemand = app.ElectricityDemandCheckBox.Value;
            hold(app.UIAxes,"on");
            
            if app.isGraphDemand
                initializeAxes(app);
                plot(app.UIAxes,app.demandData,'-r'); 
            else
                cla(app.UIAxes);
                if app.isGraphSolar
                    plot(app.UIAxes,app.solarData*app.solarDisplaySize,'-y');
                end
                yline(app.UIAxes,app.substationCapacityGraph,'--');
            end
        end

        % Value changing function: StartDatetographSlider
        function StartDatetographSliderValueChanging(app, event)
            app.startDateGraph = event.Value;
            
            if app.startDateGraph > app.endDateGraph - 10
                app.startDateGraph = app.endDateGraph - 10;
                msgbox('The start date of the data graphed cannot be the same as or later than the end date','Error','error');
            end
            
            if app.startDateGraph >= app.dataLength - 10
                app.startDateGraph = app.dataLength - 11;
                msgbox('The graph cannot show less than 10 data points');
            end
            
            xlim(app.UIAxes,[app.startDateGraph app.endDateGraph])
            
            if app.isGraphDemand
                plot(app.UIAxes,app.demandData,'-r');
            end
            if app.isGraphSolar
                plot(app.UIAxes,app.solarData*app.solarDisplaySize,'-y');
            end
        end

        % Value changing function: EndDatetographSlider
        function EndDatetographSliderValueChanging(app, event)
            app.endDateGraph = event.Value;
            
            if app.startDateGraph > app.endDateGraph - 10
                app.endDateGraph = app.startDateGraph + 10;
                msgbox('The start date of the data graphed cannot be the same as or later than the end date','Error','error');
            end
            
            if app.endDateGraph <= 10
                app.endDateGraph = 11;
                msgbox('The graph cannot show less than 10 data points');
            end
            
            xlim(app.UIAxes,[app.startDateGraph app.endDateGraph])
            
            if app.isGraphDemand
                plot(app.UIAxes,app.demandData,'-r');
            end
            if app.isGraphSolar
                plot(app.UIAxes,app.solarData*app.solarDisplaySize,'-y');
            end
        end

        % Value changing function: MinimumSlider
        function MinimumSliderValueChanging(app, event)
            app.graphYMin = event.Value;
            
            if app.graphYMin > app.graphYMax - 5
                app.graphYMin = app.graphYMax - 5;
                msgbox('The minimum data point graphed cannot be larger than the maximum data point graphed','Error','error');
            end
            
            ylim(app.UIAxes, [(app.dataMax*(app.graphYMin/100)) (app.dataMax*(app.graphYMax/100))]);
        end

        % Value changing function: MaximumSlider
        function MaximumSliderValueChanging(app, event)
            app.graphYMax = event.Value;
            
            if app.graphYMax < app.graphYMin + 5
                app.graphYMax = app.graphYMin + 5;
                msgbox('The minimum data point graphed cannot be larger than the maximum data point graphed','Error','error');
            end
            
            ylim(app.UIAxes, [(app.dataMax*(app.graphYMin/100)) (app.dataMax*(app.graphYMax/100))]);
        end

        % Value changed function: SolarScenarioAmountEditField
        function SolarScenarioAmountEditFieldValueChanged2(app, event)
            app.solarScenarioAmount = floor(app.SolarScenarioAmountEditField.Value);
            
            for i = 1:app.solarScenarioAmount
                app.SolarTable.RowName{i} = strcat('Scenario ', num2str(i));
            end
            
            nrows = size(app.SolarTable.RowName,1);
            
            app.SolarTable.Data = zeros(nrows,3);
        end

        % Value changed function: ESSScenarioAmountEditField
        function ESSScenarioAmountEditFieldValueChanged2(app, event)
            app.ESSScenarioAmount = floor(app.ESSScenarioAmountEditField.Value);
            
            for i = 1:app.ESSScenarioAmount
                app.ESSTable.RowName{i} = strcat('Scenario ', num2str(i));
            end
            
            nrows = size(app.ESSTable.RowName,1);
            
            app.ESSTable.Data = zeros(nrows,3);
        end

        % Value changed function: SubstationScenarioAmountEditField
        function SubstationScenarioAmountEditFieldValueChanged2(app, event)
            app.substationScenarioAmount = floor(app.SubstationScenarioAmountEditField.Value);
            
            for i = 1:app.substationScenarioAmount
                app.SubstationTable.RowName{i} = strcat('Scenario ', num2str(i));
            end
            
            nrows = size(app.SubstationTable.RowName,1);
            
            app.SubstationTable.Data = zeros(nrows,3);
        end

        % Value changed function: 
        % SizeofSolarArrayUploadedMWEditField
        function SizeofSolarArrayUploadedMWEditFieldValueChanged(app, event)
            app.solarInputSize = app.SizeofSolarArrayUploadedMWEditField.Value;
            
            if app.isGraphSolar
                cla(app.UIAxes);
                initializeAxes(app);
                plot(app.UIAxes,app.solarData*app.solarDisplaySize,'-y');
                if app.isGraphDemand
                    plot(app.UIAxes,app.demandData, '-r');
                end
                yline(app.UIAxes,app.substationCapacityGraph,'--');
            else
                cla(app.UIAxes);
                if app.isGraphDemand
                    plot(app.UIAxes,app.demandData, '-r');
                end
                yline(app.UIAxes,app.substationCapacityGraph,'--');
            end
            
            app.const.solarGen1MW = app.solarData ./ app.solarInputSize;
        end

        % Value changed function: SubstationCapacityMWEditField
        function SubstationCapacityMWEditFieldValueChanged(app, event)
            app.substationCapacityGraph = app.SubstationCapacityMWEditField.Value;
            
            cla(app.UIAxes);
            
            yline(app.UIAxes,app.substationCapacityGraph,'--');
            if app.isGraphDemand
                plot(app.UIAxes,app.demandData, '-r');
            end
            if app.isGraphSolar
                plot(app.UIAxes,app.solarData*app.solarDisplaySize,'-y');
            end
        end

        % Value changed function: SolarArrayScaleFactorEditField
        function SolarArrayScaleFactorEditFieldValueChanged(app, event)
            app.solarDisplaySize = app.SolarArrayScaleFactorEditField.Value;
            
            if app.isGraphSolar
                cla(app.UIAxes);
                initializeAxes(app);
                plot(app.UIAxes,app.solarData*app.solarDisplaySize,'-y');
                if app.isGraphDemand
                    plot(app.UIAxes,app.demandData, '-r');
                end
                yline(app.UIAxes,app.substationCapacityGraph,'--');
            else
                cla(app.UIAxes);
                if app.isGraphDemand
                    plot(app.UIAxes,app.demandData, '-r');
                end
                yline(app.UIAxes,app.substationCapacityGraph,'--');
            end
        end

        % Value changed function: DemandScaleFactorEditField
        function DemandScaleFactorEditFieldValueChanged(app, event)
            app.demandScaleFactor = app.DemandScaleFactorEditField.Value;
            
            if app.isGraphDemand
                cla(app.UIAxes);
                initializeAxes(app)
                plot(app.UIAxes,app.demandData,'-r');
                if app.isGraphSolar
                    plot(app.UIAxes,app.solarData,'-y');
                end
                yline(app.UIAxes,app.substationCapacityGraph,'--');
            else
                cla(app.UIAxes);
                if app.isGraphSolar
                    plot(app.UIAxes,app.solarData*app.solarDisplaySize,'-y');
                end
                yline(app.UIAxes,app.substationCapacityGraph,'--');
            end
        end

        % Selection changed function: ESSAlgorithmButtonGroup
        function ESSAlgorithmButtonGroupSelectionChanged(app, event)
            selectedButton = app.ESSAlgorithmButtonGroup.SelectedObject;
            if selectedButton.Text == 'Time-Based' %#ok<BDSCA> 
                % make fields corresponding to time-based variables editable
                app.PeakHoursEditField.Editable = 'On';
                app.PeakHoursEndTimeEditField.Editable = 'On';
                app.OffPeakHoursEditField.Editable = 'On';
                app.OffPeakHoursEndTimeEditField.Editable = 'On';
                app.EmergencyPowerofMaximumEditField.Editable = 'On';
                app.ChargebySolarThresholdEditField.Editable = 'On';
                
                % make fields corresponding to load-based variables uneditable
                app.ChargePercentageEditField.Editable = 'Off';
                app.DischargePercentageEditField.Editable = 'Off';
                app.DischargeFactorEditField.Editable = 'Off';
                
                % set const variable to tell optimization that the load-based algorithm will be used
                app.const.isLoadBasedBESS = 0;
                
            elseif selectedButton.Text == 'Load-Based' %#ok<BDSCA> 
                % make fields corresponding to time-based variables uneditable
                app.PeakHoursEditField.Editable = 'Off';
                app.PeakHoursEndTimeEditField.Editable = 'Off';
                app.OffPeakHoursEditField.Editable = 'Off';
                app.OffPeakHoursEndTimeEditField.Editable = 'Off';
                app.EmergencyPowerofMaximumEditField.Editable = 'Off';
                app.ChargebySolarThresholdEditField.Editable = 'Off';
                
                % make fields corresponding to load-based variables editable
                app.ChargePercentageEditField.Editable = 'On';
                app.DischargePercentageEditField.Editable = 'On';
                app.DischargeFactorEditField.Editable = 'On';
                
                % set const variable to tell optimization that the time-based algorithm will be used
                app.const.isLoadBasedBESS = 1;
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.WelcomeLabel = uilabel(app.HomeTab);
            app.WelcomeLabel.Text = 'Welcome';
            app.HomesteadFLButton_2 = uibutton(app.HomeTab, 'push');
            app.HomesteadFLButton_2.Text = 'Homestead, FL';
            app.ChelanCountyWAButton_2 = uibutton(app.HomeTab, 'push');
            app.ChelanCountyWAButton_2.Text = 'Chelan County, WA';
            app.GreatPlainsWestMTButton_2 = uibutton(app.HomeTab, 'push');
            app.GreatPlainsWestMTButton_2.Text = 'Great Plains West, MT';
            app.DemoModeLabel_2 = uilabel(app.HomeTab);
            app.DemoModeLabel_2.Text = 'Demo Mode';
            app.InputDataTab = uitab(app.TabGroup);
            app.InputDataTab.Title = 'Input Data';
            app.LoadSolarDataButton = uibutton(app.InputDataTab, 'push');
            app.LoadSolarDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSolarDataButtonPushed, true);
            app.LoadSolarDataButton.Text = 'Load Solar Data';
            app.LoadDemandDataButton = uibutton(app.InputDataTab, 'push');
            app.LoadDemandDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDemandDataButtonPushed, true);
            app.LoadDemandDataButton.Text = 'Load Demand Data';
            app.LoadDataLabel = uilabel(app.InputDataTab);
            app.LoadDataLabel.Text = 'Load Data';
            app.TextArea_2 = uitextarea(app.InputDataTab);
            app.TextArea_2.Value = {'Lorem ipsum dolor sit amet, consectetur adipiscing elit. Aenean ullamcorper, lacus a ornare malesuada, enim massa efficitur augue, eu commodo nibh nisi id nisl. Nunc sed libero vel metus tempor maximus ultricies eget lorem. Cras est nisl, gravida id porta sed, cursus eget felis. Proin luctus metus et lorem blandit, sit amet congue orci rutrum. Nam gravida, odio et ultricies dignissim, risus est ultrices augue, eget eleifend ante lorem ac lectus. Suspendisse ut sagittis augue, sed pulvinar neque. Curabitur justo lorem, maximus eget pulvinar eu, tincidunt non quam. Mauris posuere lectus in sodales cursus. Curabitur blandit magna id leo maximus accumsan. Phasellus non gravida orci. Duis vehicula faucibus diam et congue.'};
            app.SolarArrayScaleFactorEditFieldLabel = uilabel(app.InputDataTab);
            app.SolarArrayScaleFactorEditFieldLabel.Text = 'Solar Array Scale Factor';
            app.SolarArrayScaleFactorEditField = uieditfield(app.InputDataTab, 'numeric');
            app.SolarArrayScaleFactorEditField.ValueChangedFcn = createCallbackFcn(app, @SolarArrayScaleFactorEditFieldValueChanged, true);
            app.SolarGenerationCheckBox = uicheckbox(app.InputDataTab);
            app.SolarGenerationCheckBox.ValueChangedFcn = createCallbackFcn(app, @SolarGenerationCheckBoxValueChanged, true);
            app.SolarGenerationCheckBox.Text = 'Solar Generation';
            app.ElectricityDemandCheckBox = uicheckbox(app.InputDataTab);
            app.ElectricityDemandCheckBox.ValueChangedFcn = createCallbackFcn(app, @ElectricityDemandCheckBoxValueChanged, true);
            app.ElectricityDemandCheckBox.Text = 'Electricity Demand';
            app.DatatoGraphLabel = uilabel(app.InputDataTab);
            app.DatatoGraphLabel.Text = 'Data to Graph';
            app.StartDatetographSliderLabel = uilabel(app.InputDataTab);
            app.StartDatetographSliderLabel.Text = {'Start Date'; '(to graph)'};
            app.StartDatetographSlider = uislider(app.InputDataTab);
            app.StartDatetographSlider.Limits = [0 365];
            app.StartDatetographSlider.ValueChangingFcn = createCallbackFcn(app, @StartDatetographSliderValueChanging, true);
            app.EndDatetographSliderLabel = uilabel(app.InputDataTab);
            app.EndDatetographSliderLabel.Text = {'End Date'; '(to graph)'};
            app.EndDatetographSlider = uislider(app.InputDataTab);
            app.EndDatetographSlider.Limits = [0 365];
            app.EndDatetographSlider.ValueChangingFcn = createCallbackFcn(app, @EndDatetographSliderValueChanging, true);
            app.MaximumSliderLabel = uilabel(app.InputDataTab);
            app.MaximumSliderLabel.Text = 'Maximum';
            app.MaximumSlider = uislider(app.InputDataTab);
            app.MaximumSlider.ValueChangingFcn = createCallbackFcn(app, @MaximumSliderValueChanging, true);
            app.MinimumSliderLabel = uilabel(app.InputDataTab);
            app.MinimumSliderLabel.Text = 'Minimum';
            app.MinimumSlider = uislider(app.InputDataTab);
            app.MinimumSlider.ValueChangingFcn = createCallbackFcn(app, @MinimumSliderValueChanging, true);
            app.SizeofSolarArrayUploadedMWEditFieldLabel = uilabel(app.InputDataTab);
            app.SizeofSolarArrayUploadedMWEditFieldLabel.Text = 'Size of Solar Array Uploaded (MW)';
            app.SizeofSolarArrayUploadedMWEditField = uieditfield(app.InputDataTab, 'numeric');
            app.SizeofSolarArrayUploadedMWEditField.ValueChangedFcn = createCallbackFcn(app, @SizeofSolarArrayUploadedMWEditFieldValueChanged, true);
            app.DemandScaleFactorEditFieldLabel = uilabel(app.InputDataTab);
            app.DemandScaleFactorEditFieldLabel.Text = 'Demand Scale Factor ';
            app.DemandScaleFactorEditField = uieditfield(app.InputDataTab, 'numeric');
            app.DemandScaleFactorEditField.ValueChangedFcn = createCallbackFcn(app, @DemandScaleFactorEditFieldValueChanged, true);
            app.SubstationCapacityMWEditFieldLabel = uilabel(app.InputDataTab);
            app.SubstationCapacityMWEditFieldLabel.Text = 'Substation Capacity (MW)';
            app.SubstationCapacityMWEditField = uieditfield(app.InputDataTab, 'numeric');
            app.SubstationCapacityMWEditField.ValueChangedFcn = createCallbackFcn(app, @SubstationCapacityMWEditFieldValueChanged, true);
            app.UIAxes = uiaxes(app.InputDataTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SystemSpecificationsTab = uitab(app.TabGroup);
            app.SystemSpecificationsTab.Title = 'System Specifications';
            app.ESSAlgorithmButtonGroup = uibuttongroup(app.SystemSpecificationsTab);
            app.ESSAlgorithmButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ESSAlgorithmButtonGroupSelectionChanged, true);
            app.ESSAlgorithmButtonGroup.Title = 'ESS Algorithm';
            app.TimeBasedButton = uitogglebutton(app.ESSAlgorithmButtonGroup);
            app.TimeBasedButton.Text = 'Time-Based';
            app.TimeBasedButton.Value = true;
            app.LoadBasedButton = uitogglebutton(app.ESSAlgorithmButtonGroup);
            app.LoadBasedButton.Text = 'Load-Based';
            app.BatteryReplacementButtonGroup = uibuttongroup(app.SystemSpecificationsTab);
            app.BatteryReplacementButtonGroup.Title = 'Battery Replacement';
            app.YearsButton = uitogglebutton(app.BatteryReplacementButtonGroup);
            app.YearsButton.Text = 'Years';
            app.YearsButton.Value = true;
            app.CyclesButton = uitogglebutton(app.BatteryReplacementButtonGroup);
            app.CyclesButton.Text = 'Cycles';
            app.MinimumSOHButton = uitogglebutton(app.BatteryReplacementButtonGroup);
            app.MinimumSOHButton.Text = 'Minimum SOH';
            app.ThroughputMWhButton = uitogglebutton(app.BatteryReplacementButtonGroup);
            app.ThroughputMWhButton.Text = 'Throughput (MWh)';
            app.ESSDepthofDischargeLabel = uilabel(app.SystemSpecificationsTab);
            app.ESSDepthofDischargeLabel.Text = 'ESS Depth of Discharge (%)';
            app.ESSDepthofDischargeEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.ESSDurationhoursEditFieldLabel = uilabel(app.SystemSpecificationsTab);
            app.ESSDurationhoursEditFieldLabel.Text = 'ESS Duration (hours)';
            app.ESSDurationhoursEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.ESSInitialEnergyofMaximumEditFieldLabel = uilabel(app.SystemSpecificationsTab);
            app.ESSInitialEnergyofMaximumEditFieldLabel.Text = 'ESS Initial Energy (% of Maximum)';
            app.ESSInitialEnergyofMaximumEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.SubstationPlannedRatingMWEditFieldLabel = uilabel(app.SystemSpecificationsTab);
            app.SubstationPlannedRatingMWEditFieldLabel.Text = 'Substation Planned Rating (MW)';
            app.SubstationPlannedRatingMWEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.UnitsSwitchLabel = uilabel(app.SystemSpecificationsTab);
            app.UnitsSwitchLabel.Text = 'Units';
            app.UnitsSwitch = uiswitch(app.SystemSpecificationsTab, 'slider');
            app.UnitsSwitch.Items = {'MW', 'KW'};
            app.UnitsSwitch.Value = 'MW';
            app.PeakHoursEditFieldLabel = uilabel(app.SystemSpecificationsTab);
            app.PeakHoursEditFieldLabel.Text = 'Peak Hours';
            app.PeakHoursEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.OffPeakHoursEditFieldLabel = uilabel(app.SystemSpecificationsTab);
            app.OffPeakHoursEditFieldLabel.Text = 'Off-Peak Hours';
            app.OffPeakHoursEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.BatteryReplacementEditFieldLabel = uilabel(app.SystemSpecificationsTab);
            app.BatteryReplacementEditFieldLabel.Text = 'Battery Replacement';
            app.BatteryReplacementEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.YearlyESSCapacityLossEditFieldLabel = uilabel(app.SystemSpecificationsTab);
            app.YearlyESSCapacityLossEditFieldLabel.Text = 'Yearly ESS Capacity Loss (%)';
            app.YearlyESSCapacityLossEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.YearlySolarCapacityLossEditFieldLabel = uilabel(app.SystemSpecificationsTab);
            app.YearlySolarCapacityLossEditFieldLabel.Text = 'Yearly Solar Capacity Loss (%)';
            app.YearlySolarCapacityLossEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.MonthlyESSchargeLossEditFieldLabel = uilabel(app.SystemSpecificationsTab);
            app.MonthlyESSchargeLossEditFieldLabel.Text = 'Monthly ESS charge Loss (%)';
            app.MonthlyESSchargeLossEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.InverterEfficiencyLabel = uilabel(app.SystemSpecificationsTab);
            app.InverterEfficiencyLabel.Text = 'Inverter Efficiency (%)';
            app.InverterEfficiencyEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.DCDCConverterEfficiencyEditFieldLabel = uilabel(app.SystemSpecificationsTab);
            app.DCDCConverterEfficiencyEditFieldLabel.Text = 'DC-DC Converter Efficiency (%)';
            app.DCDCConverterEfficiencyEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.ESSRoundTripEfficiencyLabel = uilabel(app.SystemSpecificationsTab);
            app.ESSRoundTripEfficiencyLabel.Text = 'ESS Round-Trip Efficiency (%)';
            app.ESSRoundTripEfficiencyEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.HardwareReplacementIntervalYearsEditFieldLabel = uilabel(app.SystemSpecificationsTab);
            app.HardwareReplacementIntervalYearsEditFieldLabel.Text = 'Hardware Replacement Interval (Years)';
            app.HardwareReplacementIntervalYearsEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.PeakHoursEndTimeEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.OffPeakHoursEndTimeEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.SystemSpecificationsLabel = uilabel(app.SystemSpecificationsTab);
            app.SystemSpecificationsLabel.Text = 'System Specifications';
            app.EmergencyPowerofMaximumEditFieldLabel = uilabel(app.SystemSpecificationsTab);
            app.EmergencyPowerofMaximumEditFieldLabel.Text = 'Emergency Power (% of Maximum)';
            app.EmergencyPowerofMaximumEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.ChargebySolarThresholdLabel = uilabel(app.SystemSpecificationsTab);
            app.ChargebySolarThresholdLabel.Text = 'Charge by Solar Threshold (%)';
            app.ChargebySolarThresholdEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.ChargePercentageEditFieldLabel = uilabel(app.SystemSpecificationsTab);
            app.ChargePercentageEditFieldLabel.Text = 'Charge Percentage';
            app.ChargePercentageEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.DischargePercentageEditFieldLabel = uilabel(app.SystemSpecificationsTab);
            app.DischargePercentageEditFieldLabel.Text = 'Discharge Percentage';
            app.DischargePercentageEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.DischargeFactorEditFieldLabel = uilabel(app.SystemSpecificationsTab);
            app.DischargeFactorEditFieldLabel.Text = 'Discharge Factor';
            app.DischargeFactorEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.CostVariablesTab = uitab(app.TabGroup);
            app.CostVariablesTab.Title = 'Cost Variables';
            app.CarbonCostpertonCO2USDEditFieldLabel = uilabel(app.CostVariablesTab);
            app.CarbonCostpertonCO2USDEditFieldLabel.Text = 'Carbon Cost per ton CO2 (USD)';
            app.CarbonCostpertonCO2USDEditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.SolarInstallationCostperMWUSDEditFieldLabel = uilabel(app.CostVariablesTab);
            app.SolarInstallationCostperMWUSDEditFieldLabel.Text = 'Solar Installation Cost per MW (USD)';
            app.SolarInstallationCostperMWUSDEditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.SolarFixedInstallationCostUSDEditFieldLabel = uilabel(app.CostVariablesTab);
            app.SolarFixedInstallationCostUSDEditFieldLabel.Text = 'Solar Fixed Installation Cost (USD)';
            app.SolarFixedInstallationCostUSDEditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.ESSInstallationCostperMWhUSDEditFieldLabel = uilabel(app.CostVariablesTab);
            app.ESSInstallationCostperMWhUSDEditFieldLabel.Text = 'ESS Installation Cost per MWh (USD)';
            app.ESSInstallationCostperMWhUSDEditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.ESSFixedInstallationCostUSDEditFieldLabel = uilabel(app.CostVariablesTab);
            app.ESSFixedInstallationCostUSDEditFieldLabel.Text = 'ESS Fixed Installation Cost (USD)';
            app.ESSFixedInstallationCostUSDEditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.SolarOperationsandMaintenanceCostperMWUSDEditFieldLabel = uilabel(app.CostVariablesTab);
            app.SolarOperationsandMaintenanceCostperMWUSDEditFieldLabel.Text = 'Solar Operations and Maintenance Cost per MW (USD)';
            app.SolarOperationsandMaintenanceCostperMWUSDEditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.ESSOperationsandMaintenanceCostperMWhUSDEditFieldLabel = uilabel(app.CostVariablesTab);
            app.ESSOperationsandMaintenanceCostperMWhUSDEditFieldLabel.Text = 'ESS Operations and Maintenance Cost per MWh (USD)';
            app.ESSOperationsandMaintenanceCostperMWhUSDEditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.ESSFixedOperationsandMaintenanceCostUSDEditFieldLabel = uilabel(app.CostVariablesTab);
            app.ESSFixedOperationsandMaintenanceCostUSDEditFieldLabel.Text = 'ESS Fixed Operations and Maintenance Cost (USD)';
            app.ESSFixedOperationsandMaintenanceCostUSDEditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.SubstationUpgradeCostperMWUSDEditFieldLabel = uilabel(app.CostVariablesTab);
            app.SubstationUpgradeCostperMWUSDEditFieldLabel.Text = 'Substation Upgrade Cost per MW (USD)';
            app.SubstationUpgradeCostperMWUSDEditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.SolarPercentYearlyCostReductionLabel = uilabel(app.CostVariablesTab);
            app.SolarPercentYearlyCostReductionLabel.Text = 'Solar Percent Yearly Cost Reduction (%)';
            app.SolarPercentYearlyCostReductionEditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.ESSPercentYearlyCostReductionLabel = uilabel(app.CostVariablesTab);
            app.ESSPercentYearlyCostReductionLabel.Text = 'ESS Percent Yearly Cost Reduction (%)';
            app.ESSPercentYearlyCostReductionEditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.SolarFixedOperationsandMaintenanceCostUSDEditField_2Label = uilabel(app.CostVariablesTab);
            app.SolarFixedOperationsandMaintenanceCostUSDEditField_2Label.Text = 'Solar Fixed Operations and Maintenance Cost (USD)';
            app.SolarFixedOperationsandMaintenanceCostUSDEditField_2 = uieditfield(app.CostVariablesTab, 'numeric');
            app.SubstationOperationsandMaintenanceCostperMWUSDEditFieldLabel = uilabel(app.CostVariablesTab);
            app.SubstationOperationsandMaintenanceCostperMWUSDEditFieldLabel.Text = 'Substation Operations and Maintenance Cost per MW (USD)';
            app.SubstationOperationsandMaintenanceCostperMWUSDEditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.ESSReplacementCostperMWhUSDEditFieldLabel = uilabel(app.CostVariablesTab);
            app.ESSReplacementCostperMWhUSDEditFieldLabel.Text = 'ESS Replacement Cost per MWh (USD)';
            app.ESSReplacementCostperMWhUSDEditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.ESSFixedReplacementCostUSDEditFieldLabel = uilabel(app.CostVariablesTab);
            app.ESSFixedReplacementCostUSDEditFieldLabel.Text = 'ESS Fixed Replacement Cost (USD)';
            app.ESSFixedReplacementCostUSDEditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.ESSReplacementperMWhCostUSDLabel = uilabel(app.CostVariablesTab);
            app.ESSReplacementperMWhCostUSDLabel.Text = 'ESS Replacement per MWh Cost (USD)';
            app.ESSReplacementperMWhCostUSDEditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.SolarInstallationCarbonCostperMWtonsCO2EditFieldLabel = uilabel(app.CostVariablesTab);
            app.SolarInstallationCarbonCostperMWtonsCO2EditFieldLabel.Text = 'Solar Installation Carbon Cost per MW (tons CO2)';
            app.SolarInstallationCarbonCostperMWtonsCO2EditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.StorageInstallationCarbonCostperMWtonsCO2EditFieldLabel = uilabel(app.CostVariablesTab);
            app.StorageInstallationCarbonCostperMWtonsCO2EditFieldLabel.Text = 'Storage Installation Carbon Cost per MW (tons CO2)';
            app.StorageInstallationCarbonCostperMWtonsCO2EditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.SubstationUpgradeCarbonCostperMWtonsCO2EditFieldLabel = uilabel(app.CostVariablesTab);
            app.SubstationUpgradeCarbonCostperMWtonsCO2EditFieldLabel.Text = 'Substation Upgrade Carbon Cost per MW (tons CO2)';
            app.SubstationUpgradeCarbonCostperMWtonsCO2EditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.SolarOperationsandMaintenanceCarbonCostperMWtonsCO2Label = uilabel(app.CostVariablesTab);
            app.SolarOperationsandMaintenanceCarbonCostperMWtonsCO2Label.Text = 'Solar Operations and Maintenance Carbon Cost per MW (tons CO2)';
            app.SubstationUpgradeCarbonEquivalentCostperMWtonsCO2EditField_2 = uieditfield(app.CostVariablesTab, 'numeric');
            app.StorageOperationsandMaintenanceCarbonCostperMWtonsCO2Label = uilabel(app.CostVariablesTab);
            app.StorageOperationsandMaintenanceCarbonCostperMWtonsCO2Label.Text = 'Storage Operations and Maintenance Carbon Cost per MW (tons CO2)';
            app.SubstationUpgradeCarbonEquivalentCostperMWtonsCO2EditField_3 = uieditfield(app.CostVariablesTab, 'numeric');
            app.SubstationOperationsandMaintenanceCarbonCostperMWtonsCO2Label = uilabel(app.CostVariablesTab);
            app.SubstationOperationsandMaintenanceCarbonCostperMWtonsCO2Label.Text = 'Substation Operations and Maintenance Carbon Cost per MW (tons CO2)';
            app.SubstationUpgradeCarbonEquivalentCostperMWtonsCO2EditField_4 = uieditfield(app.CostVariablesTab, 'numeric');
            app.HardwareReplacementCarbonCostperMWtonsCO2Label = uilabel(app.CostVariablesTab);
            app.HardwareReplacementCarbonCostperMWtonsCO2Label.Text = 'Hardware Replacement Carbon Cost per MW (tons CO2)';
            app.SubstationUpgradeCarbonEquivalentCostperMWtonsCO2EditField_5 = uieditfield(app.CostVariablesTab, 'numeric');
            app.CostVariablesLabel = uilabel(app.CostVariablesTab);
            app.CostVariablesLabel.Text = 'Cost Variables';
            app.PeakHoursCosttoGenerateElectricityperMWUSDEditFieldLabel = uilabel(app.CostVariablesTab);
            app.PeakHoursCosttoGenerateElectricityperMWUSDEditFieldLabel.Text = 'Peak Hours Cost to Generate Electricity per MW (USD)';
            app.PeakHoursCosttoGenerateElectricityperMWUSDEditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.OffPeakHoursCosttoGenerateElectricityperMWUSDEditFieldLabel = uilabel(app.CostVariablesTab);
            app.OffPeakHoursCosttoGenerateElectricityperMWUSDEditFieldLabel.Text = 'Off-Peak Hours Cost to Generate Electricity per MW (USD)';
            app.OffPeakHoursCosttoGenerateElectricityperMWUSDEditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.InterestRateEditFieldLabel = uilabel(app.CostVariablesTab);
            app.InterestRateEditFieldLabel.Text = 'Interest Rate (%)';
            app.InterestRateEditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.CarbonCostsLabel = uilabel(app.CostVariablesTab);
            app.CarbonCostsLabel.Text = 'Carbon Costs';
            app.MonetaryCostsLabel = uilabel(app.CostVariablesTab);
            app.MonetaryCostsLabel.Text = 'Monetary Costs';
            app.OptimizationTab = uitab(app.TabGroup);
            app.OptimizationTab.Title = 'Optimization';
            app.TabGroup2 = uitabgroup(app.OptimizationTab);
            app.OptimizationVariablesTab = uitab(app.TabGroup2);
            app.OptimizationVariablesTab.Title = 'Optimization Variables';
            app.ProjectLifetimeYearsEditFieldLabel = uilabel(app.OptimizationVariablesTab);
            app.ProjectLifetimeYearsEditFieldLabel.Text = 'Project Lifetime (Years)';
            app.ProjectLifetimeYearsEditField = uieditfield(app.OptimizationVariablesTab, 'numeric');
            app.BlackoutatSubstationRatingCheckBox = uicheckbox(app.OptimizationVariablesTab);
            app.BlackoutatSubstationRatingCheckBox.Text = 'Blackout at Substation Rating';
            app.CarbonEmissionsperMWhofEnergyProducedtonsCO2EditFieldLabel = uilabel(app.OptimizationVariablesTab);
            app.CarbonEmissionsperMWhofEnergyProducedtonsCO2EditFieldLabel.Text = 'Carbon Emissions per MWh of Energy Produced (tons CO2)';
            app.CarbonEmissionsperMWhofEnergyProducedtonsCO2EditField = uieditfield(app.OptimizationVariablesTab, 'numeric');
            app.OverloadBlackoutCostperHourUSDEditFieldLabel = uilabel(app.OptimizationVariablesTab);
            app.OverloadBlackoutCostperHourUSDEditFieldLabel.Text = 'Overload/Blackout Cost per Hour (USD)';
            app.OverloadBlackoutCostperHourUSDEditField = uieditfield(app.OptimizationVariablesTab, 'numeric');
            app.FixedOverloadBlackoutCostUSDEditFieldLabel = uilabel(app.OptimizationVariablesTab);
            app.FixedOverloadBlackoutCostUSDEditFieldLabel.Text = 'Fixed Overload/Blackout Cost (USD)';
            app.FixedOverloadBlackoutCostUSDEditField = uieditfield(app.OptimizationVariablesTab, 'numeric');
            app.SolarCapacityRangeforOptimizationMWEditFieldLabel = uilabel(app.OptimizationVariablesTab);
            app.SolarCapacityRangeforOptimizationMWEditFieldLabel.Text = 'Solar Capacity Range for Optimization (MW)';
            app.SolarCapacityRangeforOptimizationMWEditField = uieditfield(app.OptimizationVariablesTab, 'numeric');
            app.StorageCapacityRangeforOptimizationMWhEditFieldLabel = uilabel(app.OptimizationVariablesTab);
            app.StorageCapacityRangeforOptimizationMWhEditFieldLabel.Text = 'Storage Capacity Range for Optimization (MWh)';
            app.StorageCapacityRangeforOptimizationMWhEditField = uieditfield(app.OptimizationVariablesTab, 'numeric');
            app.SubstationUpgradeRangerforOptimizationMWEditFieldLabel = uilabel(app.OptimizationVariablesTab);
            app.SubstationUpgradeRangerforOptimizationMWEditFieldLabel.Text = 'Substation Upgrade Ranger for Optimization (MW)';
            app.SubstationUpgradeRangerforOptimizationMWEditField = uieditfield(app.OptimizationVariablesTab, 'numeric');
            app.TextArea_3 = uitextarea(app.OptimizationVariablesTab);
            app.TextArea_3.Value = {'Lorem ipsum dolor sit amet, consectetur adipiscing elit. Mauris aliquet sollicitudin ligula, sit amet egestas erat ultricies in. Vestibulum eleifend consequat libero sed interdum. Phasellus faucibus lectus eros, ut consequat dolor iaculis a. Donec cursus ultricies felis, non consequat ligula molestie eget. Mauris mollis in ex ac venenatis. Mauris lectus augue, iaculis at neque vitae, tristique facilisis neque. Fusce iaculis neque odio, ut sagittis nunc venenatis sit amet. Cras quis aliquet augue.'; ''; 'In hac habitasse platea dictumst. Nulla tempor, ligula sed luctus dictum, tortor lectus condimentum metus, vitae elementum nulla diam tincidunt justo. Praesent accumsan et quam ac tincidunt. Aliquam ac ultrices tortor, ac malesuada libero. Morbi risus magna, placerat sed leo sit amet, dignissim volutpat velit. Curabitur lobortis eget orci a vehicula. Nunc et sollicitudin augue. Aenean sed nulla sed mauris consequat lacinia quis in eros. Pellentesque habitant morbi tristique senectus et netus et malesuada fames ac turpis egestas. In hac habitasse platea dictumst.'; ''; 'Cras viverra libero in magna ornare, ac condimentum leo iaculis. Integer a dictum magna. Cras euismod euismod massa, quis interdum felis iaculis a. Vestibulum interdum sem nec odio fringilla, ac condimentum leo sagittis. Proin fringilla, enim ac dignissim commodo, lorem neque placerat nisl, ut blandit arcu erat vel turpis. Pellentesque id augue at neque elementum viverra eget eu augue. Suspendisse facilisis sit amet elit vitae lacinia. Pellentesque vitae magna ac neque auctor ultricies interdum sit amet odio. Donec ac velit at orci semper blandit sed id urna. Nulla vel elementum lorem, sit amet volutpat lorem. Donec quis pulvinar lacus, et placerat libero.'};
            app.OptimizationVariablesLabel = uilabel(app.OptimizationVariablesTab);
            app.OptimizationVariablesLabel.Text = 'Optimization Variables';
            app.PredictedYearlyLoadGrowthEditField_2Label = uilabel(app.OptimizationVariablesTab);
            app.PredictedYearlyLoadGrowthEditField_2Label.Text = 'Predicted Yearly Load Growth (%)';
            app.PredictedYearlyLoadGrowthEditField_2 = uieditfield(app.OptimizationVariablesTab, 'numeric');
            app.ManualDataEntryTab = uitab(app.TabGroup2);
            app.ManualDataEntryTab.Title = 'Manual Data Entry';
            app.SolarTable = uitable(app.ManualDataEntryTab);
            app.SolarTable.ColumnName = {'Column 1'; 'Column 2'; ''; ''};
            app.SolarTable.RowName = {};
            app.ESSTable = uitable(app.ManualDataEntryTab);
            app.ESSTable.ColumnName = {'Column 1'; 'Column 2'; ''; ''};
            app.ESSTable.RowName = {};
            app.SubstationTable = uitable(app.ManualDataEntryTab);
            app.SubstationTable.ColumnName = {'Column 1'; 'Column 2'; ''; ''};
            app.SubstationTable.RowName = {};
            app.SolarScenarioAmountEditFieldLabel = uilabel(app.ManualDataEntryTab);
            app.SolarScenarioAmountEditFieldLabel.Text = 'Solar Scenario Amount';
            app.SolarScenarioAmountEditField = uieditfield(app.ManualDataEntryTab, 'numeric');
            app.SolarScenarioAmountEditField.ValueChangedFcn = createCallbackFcn(app, @SolarScenarioAmountEditFieldValueChanged2, true);
            app.ESSScenarioAmountEditFieldLabel = uilabel(app.ManualDataEntryTab);
            app.ESSScenarioAmountEditFieldLabel.Text = 'ESS Scenario Amount';
            app.ESSScenarioAmountEditField = uieditfield(app.ManualDataEntryTab, 'numeric');
            app.ESSScenarioAmountEditField.ValueChangedFcn = createCallbackFcn(app, @ESSScenarioAmountEditFieldValueChanged2, true);
            app.SubstationScenarioAmountEditFieldLabel = uilabel(app.ManualDataEntryTab);
            app.SubstationScenarioAmountEditFieldLabel.Text = 'Substation Scenario Amount';
            app.SubstationScenarioAmountEditField = uieditfield(app.ManualDataEntryTab, 'numeric');
            app.SubstationScenarioAmountEditField.ValueChangedFcn = createCallbackFcn(app, @SubstationScenarioAmountEditFieldValueChanged2, true);
            app.TextArea_4 = uitextarea(app.ManualDataEntryTab);
            app.TextArea_4.Value = {'Lorem ipsum dolor sit amet, consectetur adipiscing elit. Mauris aliquet sollicitudin ligula, sit amet egestas erat ultricies in. Vestibulum eleifend consequat libero sed interdum. Phasellus faucibus lectus eros, ut consequat dolor iaculis a. Donec cursus ultricies felis, non consequat ligula molestie eget. Mauris mollis in ex ac venenatis. Mauris lectus augue, iaculis at neque vitae, tristique facilisis neque. Fusce iaculis neque odio, ut sagittis nunc venenatis sit amet. Cras quis aliquet augue.'; ''};
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.TabGroup3 = uitabgroup(app.ResultsTab);
            app.OptimizationResultsTab = uitab(app.TabGroup3);
            app.OptimizationResultsTab.Title = 'Optimization Results';
            app.ReturnsandCarbonEmissionsTab = uitab(app.TabGroup3);
            app.ReturnsandCarbonEmissionsTab.Title = 'Returns and Carbon Emissions';
            app.DemandGraphsTab = uitab(app.TabGroup3);
            app.DemandGraphsTab.Title = 'Demand Graphs';
            app.OverloadsTab = uitab(app.TabGroup3);
            app.OverloadsTab.Title = 'Overloads';
            app.AboutthistoolTab = uitab(app.TabGroup);
            app.AboutthistoolTab.Title = 'About this tool';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CurrentGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end